classdef COVID19_Colombia < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        TabGroup                  matlab.ui.container.TabGroup
        PorfechaTab               matlab.ui.container.Tab
        Panel                     matlab.ui.container.Panel
        EscalalogartmicaCheckBox  matlab.ui.control.CheckBox
        SegnDropDownLabel         matlab.ui.control.Label
        SegnDropDown              matlab.ui.control.DropDown
        DiariosOAcum              matlab.ui.container.ButtonGroup
        DiariosButton             matlab.ui.control.RadioButton
        AcumuladosButton          matlab.ui.control.RadioButton
        Panel_2                   matlab.ui.container.Panel
        UIAxes                    matlab.ui.control.UIAxes
        PorsexoTab                matlab.ui.container.Tab
        Panel_6                   matlab.ui.container.Panel
        porSexoAxes               matlab.ui.control.UIAxes
        PoredadTab                matlab.ui.container.Tab
        Panel_10                  matlab.ui.container.Panel
        porEdadAxes               matlab.ui.control.UIAxes
        PoratencinTab             matlab.ui.container.Tab
        Panel_7                   matlab.ui.container.Panel
        porAtencionAxes           matlab.ui.control.UIAxes
        PorestadoTab              matlab.ui.container.Tab
        Panel_8                   matlab.ui.container.Panel
        porEstadoAxes             matlab.ui.control.UIAxes
        PortipoTab                matlab.ui.container.Tab
        Panel_9                   matlab.ui.container.Panel
        porTipoAxes               matlab.ui.control.UIAxes
        Tabladecasos              matlab.ui.container.Tab
        Panel_4                   matlab.ui.container.Panel
        UITable                   matlab.ui.control.Table
        Panel_3                   matlab.ui.container.Panel
        ActualizardatosButton     matlab.ui.control.Button
        Panel_5                   matlab.ui.container.Panel
        ButtonGroup               matlab.ui.container.ButtonGroup
        NacionalButton            matlab.ui.control.RadioButton
        PordepartamentoButton     matlab.ui.control.RadioButton
        PormunicipioButton        matlab.ui.control.RadioButton
        SeleccinDropDownLabel     matlab.ui.control.Label
        SeleccinDropDown          matlab.ui.control.DropDown
    end

    
    properties (Access = public)
        datos % Description
    end
    
    properties (Access = private)
        porFechas % Description
    end
    
    methods (Access = public)
        
        function actualizarPlot(app)
            switch app.ButtonGroup.SelectedObject
                case app.NacionalButton
                    datosPlot = app.datos;
                case app.PordepartamentoButton
                    datosPlot = app.datos(...
                        strcmp(app.datos.DepartamentoODistrito, ...
                        app.SeleccinDropDown.Value),:);
                case app.PormunicipioButton
                    datosPlot = app.datos(...
                        strcmp(app.datos.CiudadDeUbicaci__n, ...
                        app.SeleccinDropDown.Value),:);
            end
            
            app.calcPorFecha(datosPlot)
            
            switch app.SegnDropDown.Value
                case 'Fecha de diagnóstico'
                    x = app.porFechas.datesFdD;
                    y_acum = app.porFechas.porFdDAcum;
                    y_dia = app.porFechas.porFdDDia;
                case 'Fecha de notificación'
                    x = app.porFechas.datesFdNot;
                    y_acum = app.porFechas.porFdNotAcum;
                    y_dia = app.porFechas.porFdNotDia;
                case 'Fecha de reporte en Web'
                    x = app.porFechas.datesFRW;
                    y_acum = app.porFechas.porFRWAcum;
                    y_dia = app.porFechas.porFRWDia;
                case 'Fecha de inicio de síntomas'
                    x = app.porFechas.datesFIS;
                    y_acum = app.porFechas.porFISAcum;
                    y_dia = app.porFechas.porFISDia;
                case 'Fecha de recuperación'
                    x = app.porFechas.datesFdR;
                    y_acum = app.porFechas.porFdRAcum;
                    y_dia = app.porFechas.porFdRDia;
                case 'Fecha de muerte'
                    x = app.porFechas.datesFdM;
                    y_acum = app.porFechas.porFdMAcum;
                    y_dia = app.porFechas.porFdMDia;
            end
            
            acumB = app.AcumuladosButton.Value;
            if(acumB)
                y = y_acum;
            else
                y = y_dia;
            end
            
            app.UIAxes.reset()
            if (app.EscalalogartmicaCheckBox.Value)
                semilogy(app.UIAxes, x, y)
                if(~acumB)
                    bar(app.UIAxes,x,y)
                end
            else
                if(acumB)
                    plot(app.UIAxes, x, y)
                else
                    bar(app.UIAxes, x, y)
                end
            end
            
            app.UITable.Data = datosPlot;
            
            countF = sum(strcmp(datosPlot.Sexo,'F'));
            countM = sum(strcmp(datosPlot.Sexo,'M'));
            pie(app.porSexoAxes,...
                [countF countM],...
                {['Femenino (' num2str(countF) ')'],...
                ['Masculino (' num2str(countM) ')']})
            
            app.porEdadAxes.reset()
            histogram(app.porEdadAxes,datosPlot.Edad)
            pie(app.porAtencionAxes,categorical(datosPlot.atenci__n))
            pie(app.porEstadoAxes,categorical(datosPlot.Estado))
            pie(app.porTipoAxes,categorical(datosPlot.Tipo))
        end
        
        function actualizarDatos(app)
            app.datos = webread('https://www.datos.gov.co/api/views/gt2j-8ykr/rows.csv');
            
            app.datos.FechaDeNotificaci__n = datetime(extractBefore(app.datos.FechaDeNotificaci__n,"T"));
            app.datos = rmmissing(app.datos);
            
            app.datos.FIS = datetime(extractBefore(app.datos.FIS,"T"));
            app.datos.FechaDeMuerte = datetime(extractBefore(app.datos.FechaDeMuerte,"T"));
            app.datos.FechaDiagnostico = datetime(extractBefore(app.datos.FechaDiagnostico,"T"));
            app.datos.FechaRecuperado = datetime(extractBefore(app.datos.FechaRecuperado,"T"));
            app.datos.fechaReporteWeb = datetime(extractBefore(app.datos.fechaReporteWeb,"T"));
            
            app.UITable.ColumnName = fieldnames(app.datos);
            
            app.actualizarPlot()
        end
    end
    
    methods (Access = private)
        
        function calcPorFecha(app, datos)
            % Fecha de notificación
            [H,app.porFechas.datesFdNot] = discretize(datos.FechaDeNotificaci__n, 'day');
            app.porFechas.porFdNotDia = accumarray(H, 1);
            app.porFechas.porFdNotAcum = cumsum(app.porFechas.porFdNotDia);
            
            app.porFechas.datesFdNot = app.porFechas.datesFdNot(1:numel(app.porFechas.porFdNotAcum))';
            
            % Fecha de inicio de síntomas
            [H,app.porFechas.datesFIS] = discretize(datos.FIS, 'day');
            H = rmmissing(H);
            app.porFechas.porFISDia = accumarray(H, 1);
            app.porFechas.porFISAcum = cumsum(app.porFechas.porFISDia);
            
            app.porFechas.datesFIS = app.porFechas.datesFIS(1:numel(app.porFechas.porFISAcum))';
            
            % Fecha de muerte
            [H,app.porFechas.datesFdM] = discretize(datos.FechaDeMuerte, 'day');
            H = rmmissing(H);
            app.porFechas.porFdMDia = accumarray(H, 1);
            app.porFechas.porFdMAcum = cumsum(app.porFechas.porFdMDia);
            
            app.porFechas.datesFdM = app.porFechas.datesFdM(1:numel(app.porFechas.porFdMAcum))';
            
            % Fecha de diagnóstico
            [H,app.porFechas.datesFdD] = discretize(datos.FechaDiagnostico, 'day');
            H = rmmissing(H);
            app.porFechas.porFdDDia = accumarray(H, 1);
            app.porFechas.porFdDAcum = cumsum(app.porFechas.porFdDDia);
            
            app.porFechas.datesFdD = app.porFechas.datesFdD(1:numel(app.porFechas.porFdDAcum))';
            
            % Fecha de recuperación
            [H,app.porFechas.datesFdR] = discretize(datos.FechaRecuperado, 'day');
            H = rmmissing(H);
            app.porFechas.porFdRDia = accumarray(H, 1);
            app.porFechas.porFdRAcum = cumsum(app.porFechas.porFdRDia);
            
            app.porFechas.datesFdR = app.porFechas.datesFdR(1:numel(app.porFechas.porFdRAcum))';
            
            % Fecha de reporte en Web
            [H,app.porFechas.datesFRW] = discretize(datos.fechaReporteWeb, 'day');
            H = rmmissing(H);
            app.porFechas.porFRWDia = accumarray(H, 1);
            app.porFechas.porFRWAcum = cumsum(app.porFechas.porFRWDia);
            
            app.porFechas.datesFRW = app.porFechas.datesFRW(1:numel(app.porFechas.porFRWAcum))';
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.actualizarDatos()
        end

        % Button pushed function: ActualizardatosButton
        function ActualizardatosButtonPushed(app, event)
            app.ActualizardatosButton.Enable = "off";
            pause(0.2)
            app.actualizarDatos()
            app.ActualizardatosButton.Enable = "on";
        end

        % Value changed function: EscalalogartmicaCheckBox
        function EscalalogartmicaCheckBoxValueChanged(app, event)
            app.actualizarPlot()
        end

        % Value changed function: SegnDropDown
        function SegnDropDownValueChanged(app, event)
            app.actualizarPlot()
        end

        % Selection changed function: ButtonGroup
        function ButtonGroupSelectionChanged(app, event)
            app.SeleccinDropDown.Visible = true;
            switch app.ButtonGroup.SelectedObject
                case app.NacionalButton
                    app.SeleccinDropDown.Visible = false;
                    app.actualizarPlot()
                case app.PordepartamentoButton
                    app.SeleccinDropDown.Items = [' '; unique(app.datos.DepartamentoODistrito)];
                case app.PormunicipioButton
                    app.SeleccinDropDown.Items = [' '; unique(app.datos.CiudadDeUbicaci__n)];
            end
            app.SeleccinDropDown.Value = ' ';
        end

        % Value changed function: SeleccinDropDown
        function SeleccinDropDownValueChanged(app, event)
            if(~strcmp(app.SeleccinDropDown.Value,' '))
                app.actualizarPlot()
            end
        end

        % Selection changed function: DiariosOAcum
        function DiariosOAcumSelectionChanged(app, event)
            app.actualizarPlot()
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.PorfechaTab = uitab(app.TabGroup);
            app.PorfechaTab.Title = 'Por fecha';
            app.Panel = uipanel(app.PorfechaTab);
            app.EscalalogartmicaCheckBox = uicheckbox(app.Panel);
            app.EscalalogartmicaCheckBox.ValueChangedFcn = createCallbackFcn(app, @EscalalogartmicaCheckBoxValueChanged, true);
            app.EscalalogartmicaCheckBox.Text = 'Escala logarítmica';
            app.SegnDropDownLabel = uilabel(app.Panel);
            app.SegnDropDownLabel.Text = 'Según';
            app.SegnDropDown = uidropdown(app.Panel);
            app.SegnDropDown.Items = {'Fecha de diagnóstico', 'Fecha de notificación', 'Fecha de reporte en Web', 'Fecha de inicio de síntomas', 'Fecha de recuperación', 'Fecha de muerte'};
            app.SegnDropDown.ValueChangedFcn = createCallbackFcn(app, @SegnDropDownValueChanged, true);
            app.SegnDropDown.Value = 'Fecha de diagnóstico';
            app.DiariosOAcum = uibuttongroup(app.Panel);
            app.DiariosOAcum.SelectionChangedFcn = createCallbackFcn(app, @DiariosOAcumSelectionChanged, true);
            app.DiariosButton = uiradiobutton(app.DiariosOAcum);
            app.DiariosButton.Text = 'Diarios';
            app.DiariosButton.Value = true;
            app.AcumuladosButton = uiradiobutton(app.DiariosOAcum);
            app.AcumuladosButton.Text = 'Acumulados';
            app.Panel_2 = uipanel(app.PorfechaTab);
            app.UIAxes = uiaxes(app.Panel_2);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.PorsexoTab = uitab(app.TabGroup);
            app.PorsexoTab.Title = 'Por sexo';
            app.Panel_6 = uipanel(app.PorsexoTab);
            app.porSexoAxes = uiaxes(app.Panel_6);
            title(app.porSexoAxes, '')
            xlabel(app.porSexoAxes, 'X')
            ylabel(app.porSexoAxes, 'Y')
            app.PoredadTab = uitab(app.TabGroup);
            app.PoredadTab.Title = 'Por edad';
            app.Panel_10 = uipanel(app.PoredadTab);
            app.porEdadAxes = uiaxes(app.Panel_10);
            title(app.porEdadAxes, '')
            xlabel(app.porEdadAxes, 'X')
            ylabel(app.porEdadAxes, 'Y')
            app.PoratencinTab = uitab(app.TabGroup);
            app.PoratencinTab.Title = 'Por atención';
            app.Panel_7 = uipanel(app.PoratencinTab);
            app.porAtencionAxes = uiaxes(app.Panel_7);
            title(app.porAtencionAxes, '')
            xlabel(app.porAtencionAxes, 'X')
            ylabel(app.porAtencionAxes, 'Y')
            app.PorestadoTab = uitab(app.TabGroup);
            app.PorestadoTab.Title = 'Por estado';
            app.Panel_8 = uipanel(app.PorestadoTab);
            app.porEstadoAxes = uiaxes(app.Panel_8);
            title(app.porEstadoAxes, '')
            xlabel(app.porEstadoAxes, 'X')
            ylabel(app.porEstadoAxes, 'Y')
            app.PortipoTab = uitab(app.TabGroup);
            app.PortipoTab.Title = 'Por tipo';
            app.Panel_9 = uipanel(app.PortipoTab);
            app.porTipoAxes = uiaxes(app.Panel_9);
            title(app.porTipoAxes, '')
            xlabel(app.porTipoAxes, 'X')
            ylabel(app.porTipoAxes, 'Y')
            app.Tabladecasos = uitab(app.TabGroup);
            app.Tabladecasos.Tooltip = {'Casos reportados'};
            app.Tabladecasos.Title = 'Tabla de casos';
            app.Panel_4 = uipanel(app.Tabladecasos);
            app.UITable = uitable(app.Panel_4);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            app.Panel_3 = uipanel(app.UIFigure);
            app.ActualizardatosButton = uibutton(app.Panel_3, 'push');
            app.ActualizardatosButton.ButtonPushedFcn = createCallbackFcn(app, @ActualizardatosButtonPushed, true);
            app.ActualizardatosButton.Text = 'Actualizar datos';
            app.Panel_5 = uipanel(app.UIFigure);
            app.ButtonGroup = uibuttongroup(app.Panel_5);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.NacionalButton = uiradiobutton(app.ButtonGroup);
            app.NacionalButton.Text = 'Nacional';
            app.NacionalButton.Value = true;
            app.PordepartamentoButton = uiradiobutton(app.ButtonGroup);
            app.PordepartamentoButton.Text = 'Por departamento';
            app.PormunicipioButton = uiradiobutton(app.ButtonGroup);
            app.PormunicipioButton.Text = 'Por municipio';
            app.SeleccinDropDownLabel = uilabel(app.Panel_5);
            app.SeleccinDropDownLabel.Visible = 'off';
            app.SeleccinDropDownLabel.Text = 'Selección';
            app.SeleccinDropDown = uidropdown(app.Panel_5);
            app.SeleccinDropDown.ValueChangedFcn = createCallbackFcn(app, @SeleccinDropDownValueChanged, true);
            app.SeleccinDropDown.Visible = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = COVID19_Colombia

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end